classdef InterfazApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DescargardatosMPU6050Button  matlab.ui.control.Button
        KPEditFieldLabel             matlab.ui.control.Label
        KPEditField                  matlab.ui.control.EditField
        KIEditFieldLabel             matlab.ui.control.Label
        KIEditField                  matlab.ui.control.EditField
        KDEditFieldLabel             matlab.ui.control.Label
        KDEditField                  matlab.ui.control.EditField
        EnviarparmetrosButton        matlab.ui.control.Button
        AjustarparmetrosLabel        matlab.ui.control.Label
        DescargarDatosdenguloLabel   matlab.ui.control.Label
        BallandBeamSystemLabel       matlab.ui.control.Label
        DatosdelaMPULabel            matlab.ui.control.Label
        ngulodereferenciaLabel       matlab.ui.control.Label
        ngulodereferenciaEditField   matlab.ui.control.NumericEditField
        AnguloGaugeLabel             matlab.ui.control.Label
        AnguloGauge                  matlab.ui.control.LinearGauge
        AcelermetroLabel             matlab.ui.control.Label
        GiroscopioLabel              matlab.ui.control.Label
        xEditFieldLabel              matlab.ui.control.Label
        xEditField                   matlab.ui.control.EditField
        yEditFieldLabel              matlab.ui.control.Label
        yEditField                   matlab.ui.control.EditField
        zEditFieldLabel              matlab.ui.control.Label
        zEditField                   matlab.ui.control.EditField
        gxEditFieldLabel             matlab.ui.control.Label
        gxEditField                  matlab.ui.control.EditField
        gyEditFieldLabel             matlab.ui.control.Label
        gyEditField                  matlab.ui.control.EditField
        gzEditFieldLabel             matlab.ui.control.Label
        gzEditField                  matlab.ui.control.EditField
        EditField                    matlab.ui.control.EditField
        UIAxes2                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global x
            global y
            global z
            global x_a 
            global y_a
            global z_a
            global angle

            %t = linspace(0,40,N)';
            N = 1000;
            K = 10;
            
            if exist ("s", "var")==0
                s = serialport('COM6',115200);
            end
            h1 = plot(app.UIAxes2,zeros(N,1));
            %xlim(app.UIAxes2,[0,t(end)]);
            ylim(app.UIAxes2,[-5,5]);
            buffer = zeros(K,1);
            k = 1;
            w=1;  
                
            while(1)
                 data = readline(s);
                 data_split = split(data,"|");
                 x = data_split(1);
                 y = data_split(2);
                 z = data_split(3);
                 x_a = data_split(4);
                 y_a = data_split(5);
                 z_a = data_split(6);
                 %angx=data_split(7);
                 y1=str2double(y);
%                 z1=str2double(z);
%                 xa1=str2double(x_a);
%                 ya1=str2double(y_a);
%                 za1=str2double(z_a);
                ang= 90+y1;
                angle=num2str(ang);
                app.xEditField.Value=x;
                app.yEditField.Value=y
                app.zEditField.Value=z;
                app.gxEditField.Value=x_a;
                app.gyEditField.Value=y_a;
                app.gzEditField.Value=z_a;
                app.EditField.Value=angle;
                app.AnguloGauge.Value=ang;
                 p(w,1) = str2double(y);
                 drawnow limitrate
                         buffer(k) = p(w);
 
                 if(k == K)
                     h1.YData((w-K+1):w) = buffer;   
                     drawnow limitrate
                     k = 1;
                 else
                     k = k + 1;    
                 end
                w=w+1;

            end      

          
        end

        % Button pushed function: DescargardatosMPU6050Button
        function DescargardatosMPU6050ButtonPushed(app, event)
            global x
            global y
            global z
            global x_a 
            global y_a
            global z_a
            global angle

            [usfile, uspath] = uiputfile(".txt");
            if usfile == 0
              % User clicked the Cancel button.
              return;
 
            end
            fullFileName = fullfile(uspath, usfile)

            fileID = fopen(usfile,'w');
            fprintf(fileID, 'Datos del Sistema \n\n');
            fprintf(fileID, 'Acelerómetro \n');
            fprintf(fileID, 'X: %i \n', str2double(app.xEditField.Value));
            fprintf(fileID, 'Y: %i \n', str2double(app.yEditField.Value));
            fprintf(fileID, 'Z: %i \n \n', str2double(app.zEditField.Value));
            fprintf(fileID, 'Giróscopo \n');
            fprintf(fileID, 'X: %i \n', str2double(app.gxEditField.Value));
            fprintf(fileID, 'Y: %i \n', str2double(app.gyEditField.Value));
            fprintf(fileID, 'Z: %i \n \n', str2double(app.gzEditField.Value));
            fprintf(fileID, 'Ángulo de la base: %i \n', str2double(app.EditField.Value));
            fclose(fileID);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DescargardatosMPU6050Button = uibutton(app.UIFigure, 'push');
            app.DescargardatosMPU6050Button.ButtonPushedFcn = createCallbackFcn(app, @DescargardatosMPU6050ButtonPushed, true);
            app.DescargardatosMPU6050Button.Text = 'Descargar datos MPU6050';
            app.KPEditFieldLabel = uilabel(app.UIFigure);
            app.KPEditFieldLabel.Text = 'KP';
            app.KPEditField = uieditfield(app.UIFigure, 'text');
            app.KIEditFieldLabel = uilabel(app.UIFigure);
            app.KIEditFieldLabel.Text = 'KI';
            app.KIEditField = uieditfield(app.UIFigure, 'text');
            app.KDEditFieldLabel = uilabel(app.UIFigure);
            app.KDEditFieldLabel.Text = 'KD';
            app.KDEditField = uieditfield(app.UIFigure, 'text');
            app.EnviarparmetrosButton = uibutton(app.UIFigure, 'push');
            app.EnviarparmetrosButton.Text = 'Enviar parámetros';
            app.AjustarparmetrosLabel = uilabel(app.UIFigure);
            app.AjustarparmetrosLabel.Text = 'Ajustar parámetros';
            app.DescargarDatosdenguloLabel = uilabel(app.UIFigure);
            app.DescargarDatosdenguloLabel.Text = 'Descargar Datos de Ángulo';
            app.BallandBeamSystemLabel = uilabel(app.UIFigure);
            app.BallandBeamSystemLabel.Text = 'Ball and Beam System';
            app.DatosdelaMPULabel = uilabel(app.UIFigure);
            app.DatosdelaMPULabel.Text = 'Datos de la MPU';
            app.ngulodereferenciaLabel = uilabel(app.UIFigure);
            app.ngulodereferenciaLabel.Text = {'Ángulo de'; ' referencia'; ''};
            app.ngulodereferenciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnguloGaugeLabel = uilabel(app.UIFigure);
            app.AnguloGaugeLabel.Text = 'Angulo';
            app.AnguloGauge = uigauge(app.UIFigure, 'linear');
            app.AnguloGauge.Limits = [80 100];
            app.AnguloGauge.MajorTicks = [80 90 100];
            app.AcelermetroLabel = uilabel(app.UIFigure);
            app.AcelermetroLabel.Text = 'Acelerómetro';
            app.GiroscopioLabel = uilabel(app.UIFigure);
            app.GiroscopioLabel.Text = 'Giroscopio';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'text');
            app.gxEditFieldLabel = uilabel(app.UIFigure);
            app.gxEditFieldLabel.Text = 'gx';
            app.gxEditField = uieditfield(app.UIFigure, 'text');
            app.gyEditFieldLabel = uilabel(app.UIFigure);
            app.gyEditFieldLabel.Text = 'gy';
            app.gyEditField = uieditfield(app.UIFigure, 'text');
            app.gzEditFieldLabel = uilabel(app.UIFigure);
            app.gzEditFieldLabel.Text = 'gz';
            app.gzEditField = uieditfield(app.UIFigure, 'text');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Ángulo')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end